classdef SignalAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        InputPanel                matlab.ui.container.Panel
        EndFrameLabel             matlab.ui.control.Label
        StartFrameLabel           matlab.ui.control.Label
        FolderPath                matlab.ui.control.EditField
        BrowseFolder              matlab.ui.control.Button
        FilenameNucCh             matlab.ui.control.EditField
        BrowseNucCh               matlab.ui.control.Button
        FilenameCaCh              matlab.ui.control.EditField
        BrowseCaCh                matlab.ui.control.Button
        Channel2Label             matlab.ui.control.Label
        StartFrame                matlab.ui.control.NumericEditField
        EndFrame                  matlab.ui.control.NumericEditField
        SetInputs                 matlab.ui.control.Button
        Channel1Label             matlab.ui.control.Label
        DirectoryEditFieldLabel   matlab.ui.control.Label
        SignalAnalysisToolsPanel  matlab.ui.container.Panel
        Overlay                   matlab.ui.control.Button
        SelectROI                 matlab.ui.control.Button
        CalculateSignal           matlab.ui.control.Button
        SamplingRate              matlab.ui.control.NumericEditField
        MeanDistance              matlab.ui.control.NumericEditField
        PlotRawSignal             matlab.ui.control.Button
        MeanDistLabel             matlab.ui.control.Label
        PlotNormalisedSignal      matlab.ui.control.Button
        MakeGraphMovie            matlab.ui.control.Button
        SamplingRateLabel         matlab.ui.control.Label
        OutputPanel               matlab.ui.container.ButtonGroup
        OutputFolder              matlab.ui.control.EditField
        OutputNameLabel           matlab.ui.control.Label
        SaveButton                matlab.ui.control.Button
        Session                   matlab.ui.control.RadioButton
    end

    properties (Access = private)
        FilePaths           % structure containing full pathnames of input files
        OutputPath          % output file locations
        TifInfo             % information about data
        N_frames            % number of frames in movie
        N_channels          % number of channels we have inputted data for
        OverlayedImages     % images to save structure

        Signal
        binaryImage
        
    end

    methods (Access = public)     
        function UpdateOffset(app, selected)
            app.offset = selected;
            %msgbox(app.offset);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: BrowseFolder, FolderPath
        function BrowseButtonPushed(app, event)
            app.FolderPath.Value = strcat(string(uigetdir),'\');
        end

        % Button pushed function: BrowseNucCh
        function BrowseNucChButtonPushed(app, event)
             app.FilenameNucCh.Value = string( ...
                uigetfile({'*.tif','*.ome'}, ...
                'Select a File', app.FolderPath.Value) ...
                );
        end

        % Button pushed function: BrowseCaCh
        function BrowseCaChButtonPushed(app, event)
            app.FilenameCaCh.Value = string( ...
                uigetfile({'*.tif','*.ome'}, ...
                'Select a File', app.FolderPath.Value) ...
                );
        end

        % Button pushed function: SetInputs
        function SetInputsPushed(app, event)
            addpath(genpath('utility'));
            % Initialise user input variables
            [app.FilePaths, app.OutputPath, app.TifInfo, app.N_frames, app.N_channels, app.EndFrame.Value] = initialise( ...
                app.FolderPath.Value, app.FilenameNucCh.Value, app.FilenameCaCh.Value, ...
                app.StartFrame.Value, app.EndFrame.Value, app.OutputFolder.Value);
            % Display for visual checking
            figure(1);
            subplot(1,2,1)
            imshow(imread(app.FilePaths{1}, app.StartFrame.Value));
            title('First frame')
            subplot(1,2,2);
            imshow(imread(app.FilePaths{1}, app.EndFrame.Value));
            title('Last frame');
        end

        % Button pushed function: Overlay
        function OverlayPushed(app, event)
            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('Overlaying movie frames');

            % Perform overlay and save
            OverlayedNuc = overlay(app.FilePaths{1}, app.TifInfo, app.StartFrame.Value, app.EndFrame.Value, app.N_frames);
            imwrite(OverlayedNuc, [app.OutputPath, filesep, 'OverlayedNuc.tif'], 'tif')
            OverlayedCa = overlay(app.FilePaths{2}, app.TifInfo, app.StartFrame.Value, app.EndFrame.Value, app.N_frames);
            imwrite(OverlayedCa, [app.OutputPath, filesep, 'OverlayedCa.tif'], 'tif')
            app.OverlayedImages = {OverlayedNuc, OverlayedCa};
            % Display for visual checking
            figure(2);
            subplot(1,2,1)
            imshow(OverlayedNuc);
            title('Overlayed Nuclear Channel')
            subplot(1,2,2);
            imshow(OverlayedCa);
            title('Overlayed Calcium Channel'); 

            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msgbox('Overlay complete')
        end

        % Button pushed function: SelectROI
        function SelectROIButtonPushed(app, event)
            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msgbox('Left click and hold to begin drawing ROI. Simply lift the mouse button to finish')
            % Draw region of interest
            figure(3);
            NucOverlay = mat2gray(app.OverlayedImages{1});
            CaOverlay = mat2gray(app.OverlayedImages{2});
            fusedChannel = imfuse(CaOverlay, NucOverlay);
            imshow(fusedChannel)
            axis on;
            title('Left click and hold to begin drawing ROI. Simply lift the mouse button to finish');
            hFH = imfreehand(); % drawrectangle
            app.binaryImage = hFH.createMask();
            close 3;
            % Save region of interest
            figure(4);
            imshow(fusedChannel);
            hold on;
            boundaries = bwboundaries(app.binaryImage);
            numberOfBoundaries = size(boundaries, 1);
            for k = 1:numberOfBoundaries
                thisBoundary = boundaries{k};
                plot(thisBoundary(:,2), thisBoundary(:,1), 'y', 'LineWidth', 1);
            end
            hold off;
            imageName = fullfile(app.OutputPath, 'ROI');
            saveas(gcf, imageName, 'tiff');
            close 4;
        end

        % Button pushed function: CalculateSignal
        function CalculateSignalButtonPushed(app, event)
            SignalTmp = cell(1,app.N_channels);
            app.Signal = cell(1,app.N_channels);
            for ch = 1:app.N_channels
                for frame = app.StartFrame.Value:app.EndFrame.Value
                    img = imread(app.FilePaths{ch}, frame);
                    ROI_mean_intensity = regionprops(app.binaryImage, img, 'MeanIntensity');  
                    SignalTmp{ch} = [SignalTmp{ch}, ROI_mean_intensity];
                end
                app.Signal{ch} = [SignalTmp{ch}.MeanIntensity]';
            end
        end

        % Button pushed function: PlotRawSignal
        function PlotRawSignalButtonPushed(app, event)
            NucSignal = app.Signal{1};
            CaSignal = app.Signal{2};

            xaxisScale = app.MeanDistance.Value*app.SamplingRate.Value; % time in (ms) 
            xaxisScale2 = app.MeanDistance.Value;  % time in (# movie frames)
            figure
            plot(0:1:(app.N_frames-1)*1, CaSignal, 'g');
            hold on
            plot(0:1:(app.N_frames-1)*1, NucSignal, 'm');
            xlabel('time (frames in SelectedT movie)');
            ylabel('Calcium signal and Nuclear signal');
            legend('CaSignal','NucSignal')
            imageName = fullfile(app.OutputPath,'Calcium and Nuclear Signal 3');
            saveas(gcf,imageName,'tiff');
        end

        % Button pushed function: PlotNormalisedSignal
        function PlotNormalisedSignalButtonPushed(app, event)
            NucSignal = app.Signal{1};
            CaSignal = app.Signal{2};
            Baseline = min(CaSignal./NucSignal);
            NormSignalByBackground = (CaSignal./NucSignal - Baseline)/(Baseline);
            figure
            plot(0:1:(app.N_frames-1)*1, NormSignalByBackground, 'k');
            xlabel('time (frames in SelectedT movie)');
            ylabel('Normalised Calcium Signal');
            imageName = fullfile(app.OutputPath,'Normalised to baseline 3');
            saveas(gcf,imageName,'tiff');

        end

        % Button pushed function: MakeGraphMovie
        function MakeGraphMovieButtonPushed(app, event)
            outputFolder2 = '\GraphMovie';
            folder_mov=[folder_w,outputFolder2];
            mkdir (folder_mov);
            for i = 1:length(NormSignalByBackground)
                
                figure
                plot(0:xaxisScale:xaxisScale*(i-1), NormSignalByBackground(1:i), 'k');
                xlabel('time (ms)');
                ylabel('Normalised Calcium Signal');
                axis([0,4*10^4,0,max(NormSignalByBackground)]);
                iName = string(i);
                imageName = fullfile(folder_mov,iName);
                saveas(gcf,imageName,'tiff');
            
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            close all;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OutputPanel = uibuttongroup(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.Session = uiradiobutton(app.OutputPanel);
            app.Session.Tag = 'Session';
            app.Session.Text = 'Save session settings';
            app.Session.Value = true;
            app.SaveButton = uibutton(app.OutputPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OutputNameLabel = uilabel(app.OutputPanel);
            app.OutputNameLabel.Text = 'Output Folder';
            app.OutputFolder = uieditfield(app.OutputPanel, 'text');
            app.OutputFolder.Value = 'CaSignalVsTime';
            app.SignalAnalysisToolsPanel = uipanel(app.UIFigure);
            app.SignalAnalysisToolsPanel.Title = 'Signal Analysis Tools';
            app.SamplingRateLabel = uilabel(app.SignalAnalysisToolsPanel);
            app.SamplingRateLabel.Text = 'Sampling Rate';
            app.MakeGraphMovie = uibutton(app.SignalAnalysisToolsPanel, 'push');
            app.MakeGraphMovie.ButtonPushedFcn = createCallbackFcn(app, @MakeGraphMovieButtonPushed, true);
            app.MakeGraphMovie.Text = 'Make Graph Movie';
            app.PlotNormalisedSignal = uibutton(app.SignalAnalysisToolsPanel, 'push');
            app.PlotNormalisedSignal.ButtonPushedFcn = createCallbackFcn(app, @PlotNormalisedSignalButtonPushed, true);
            app.PlotNormalisedSignal.Text = 'Plot Normalised Calcium Level';
            app.MeanDistLabel = uilabel(app.SignalAnalysisToolsPanel);
            app.MeanDistLabel.Text = 'Mean Dist';
            app.PlotRawSignal = uibutton(app.SignalAnalysisToolsPanel, 'push');
            app.PlotRawSignal.ButtonPushedFcn = createCallbackFcn(app, @PlotRawSignalButtonPushed, true);
            app.PlotRawSignal.Text = 'Plot Raw Data';
            app.MeanDistance = uieditfield(app.SignalAnalysisToolsPanel, 'numeric');
            app.MeanDistance.Limits = [1 Inf];
            app.MeanDistance.Value = 2;
            app.SamplingRate = uieditfield(app.SignalAnalysisToolsPanel, 'numeric');
            app.SamplingRate.Limits = [0 Inf];
            app.SamplingRate.RoundFractionalValues = 'on';
            app.SamplingRate.Value = 3;
            app.CalculateSignal = uibutton(app.SignalAnalysisToolsPanel, 'push');
            app.CalculateSignal.ButtonPushedFcn = createCallbackFcn(app, @CalculateSignalButtonPushed, true);
            app.CalculateSignal.Text = 'Calculate Signal vs Time';
            app.SelectROI = uibutton(app.SignalAnalysisToolsPanel, 'push');
            app.SelectROI.ButtonPushedFcn = createCallbackFcn(app, @SelectROIButtonPushed, true);
            app.SelectROI.Text = 'Select Region of Interest';
            app.Overlay = uibutton(app.SignalAnalysisToolsPanel, 'push');
            app.Overlay.ButtonPushedFcn = createCallbackFcn(app, @OverlayPushed, true);
            app.Overlay.Text = 'Overlay';
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.DirectoryEditFieldLabel = uilabel(app.InputPanel);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.Channel1Label = uilabel(app.InputPanel);
            app.Channel1Label.Text = 'Ca Ch';
            app.SetInputs = uibutton(app.InputPanel, 'push');
            app.SetInputs.ButtonPushedFcn = createCallbackFcn(app, @SetInputsPushed, true);
            app.SetInputs.Text = 'Set';
            app.EndFrame = uieditfield(app.InputPanel, 'numeric');
            app.StartFrame = uieditfield(app.InputPanel, 'numeric');
            app.StartFrame.Limits = [0 Inf];
            app.StartFrame.RoundFractionalValues = 'on';
            app.StartFrame.Value = 1;
            app.Channel2Label = uilabel(app.InputPanel);
            app.Channel2Label.Text = 'Nuc Ch';
            app.BrowseCaCh = uibutton(app.InputPanel, 'push');
            app.BrowseCaCh.ButtonPushedFcn = createCallbackFcn(app, @BrowseCaChButtonPushed, true);
            app.BrowseCaCh.Text = 'Browse';
            app.FilenameCaCh = uieditfield(app.InputPanel, 'text');
            app.BrowseNucCh = uibutton(app.InputPanel, 'push');
            app.BrowseNucCh.ButtonPushedFcn = createCallbackFcn(app, @BrowseNucChButtonPushed, true);
            app.BrowseNucCh.Text = 'Browse';
            app.FilenameNucCh = uieditfield(app.InputPanel, 'text');
            app.BrowseFolder = uibutton(app.InputPanel, 'push');
            app.BrowseFolder.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseFolder.Text = 'Browse';
            app.FolderPath = uieditfield(app.InputPanel, 'text');
            app.FolderPath.ValueChangedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.StartFrameLabel = uilabel(app.InputPanel);
            app.StartFrameLabel.Text = 'Start Frame';
            app.EndFrameLabel = uilabel(app.InputPanel);
            app.EndFrameLabel.Text = 'End Frame';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalAnalysis

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end